classdef FirstApp < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                  matlab.ui.Figure
    CounterLabel              matlab.ui.control.Label
    ServoValueEditField       matlab.ui.control.NumericEditField
    ServoValueEditFieldLabel  matlab.ui.control.Label
    Image                     matlab.ui.control.Image
    GreenLEDPanel             matlab.ui.container.Panel
    OFFButton                 matlab.ui.control.Button
    ONButton                  matlab.ui.control.Button
    ServoSlider               matlab.ui.control.Slider
    ServoSliderLabel          matlab.ui.control.Label
  end

  
  properties (Access = private)
    counter
    s
  end
  
  methods (Access = private)
    
    function serialCallback(app,serialObj,eventData)
      message = strtrim(app.s.readline());
      if(strcmp(message,"B"))
        app.counter = app.counter+1;
        app.CounterLabel.Text = sprintf("Counter = %d",app.counter);
      end
      if(strncmp(message,"X ",2))
        value = str2double(extractAfter(message,"X "));
        app.Image.Position(1) = value/2;
      end
      if(strncmp(message,"Y ",2))
        value = str2double(extractAfter(message,"Y "));
        app.Image.Position(2) = value/2;
      end
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.counter = 0;
      app.s = serialport("COM6",9600);
      configureCallback(app.s,"terminator",@app.serialCallback)
    end

    % Button pushed function: ONButton
    function ONButtonPushed(app, event)
      app.s.writeline("LED ON")
      app.OFFButton.Enable = true;
      app.ONButton.Enable = false;
    end

    % Button pushed function: OFFButton
    function OFFButtonPushed(app, event)
      app.s.writeline("LED OFF")
      app.OFFButton.Enable = false;
      app.ONButton.Enable = true;
    end

    % Value changed function: ServoValueEditField
    function ServoValueEditFieldValueChanged(app, event)
      value = round(app.ServoValueEditField.Value);
      app.s.writeline(sprintf("SERVO %d",value));
      app.ServoSlider.Value = value;
      
    end

    % Value changed function: ServoSlider
    function ServoSliderValueChanged(app, event)
      app.s.writeline(sprintf("SERVO %d",round(app.ServoSlider.Value)));
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.ServoSliderLabel = uilabel(app.UIFigure);
      app.ServoSliderLabel.Text = 'Servo Slider';
      app.ServoSlider = uislider(app.UIFigure);
      app.ServoSlider.Limits = [0 180];
      app.ServoSlider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
      app.GreenLEDPanel = uipanel(app.UIFigure);
      app.GreenLEDPanel.Title = 'Green LED';
      app.ONButton = uibutton(app.GreenLEDPanel, 'push');
      app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
      app.ONButton.Text = 'ON';
      app.OFFButton = uibutton(app.GreenLEDPanel, 'push');
      app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
      app.OFFButton.Enable = 'off';
      app.OFFButton.Text = 'OFF';
      app.Image = uiimage(app.UIFigure);
      app.Image.ImageSource = 'My Icon Alt.png';
      app.ServoValueEditFieldLabel = uilabel(app.UIFigure);
      app.ServoValueEditFieldLabel.Text = 'Servo Value';
      app.ServoValueEditField = uieditfield(app.UIFigure, 'numeric');
      app.ServoValueEditField.ValueChangedFcn = createCallbackFcn(app, @ServoValueEditFieldValueChanged, true);
      app.CounterLabel = uilabel(app.UIFigure);
      app.CounterLabel.Text = 'Counter = 0';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = FirstApp

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end